classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ForwardKinematicsTab      matlab.ui.container.Tab
        Z_valueLabel              matlab.ui.control.Label
        Y_valueLabel              matlab.ui.control.Label
        X_valueLabel              matlab.ui.control.Label
        PositionZLabel            matlab.ui.control.Label
        PositionYLabel            matlab.ui.control.Label
        PositionXLabel            matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        ForwardKinematicsLabel    matlab.ui.control.Label
        InverseKinematicsTab      matlab.ui.container.Tab
        SolutionsLabel            matlab.ui.control.Label
        mmLabel_3                 matlab.ui.control.Label
        mmLabel_2                 matlab.ui.control.Label
        mmLabel                   matlab.ui.control.Label
        DesiredPositionLabel      matlab.ui.control.Label
        InverseKinematicsLabel    matlab.ui.control.Label
        EntervaluesButton         matlab.ui.control.Button
        ZEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel           matlab.ui.control.Label
        TrajectoryPlanningTab     matlab.ui.container.Tab
        TrajectoryPlanningLabel   matlab.ui.control.Label
        ArduinoConnectionLabel    matlab.ui.control.Label
        SerialPortEditField       matlab.ui.control.EditField
        SerialPortEditFieldLabel  matlab.ui.control.Label
        q3_valueLabel             matlab.ui.control.Label
        q2_valueLabel             matlab.ui.control.Label
        q1_valueLabel             matlab.ui.control.Label
        ConnectButton             matlab.ui.control.Button
        ManualModeLabel           matlab.ui.control.Label
        Q3Knob                    matlab.ui.control.Knob
        Q3KnobLabel               matlab.ui.control.Label
        Q2Knob                    matlab.ui.control.Knob
        Q2KnobLabel               matlab.ui.control.Label
        Q1Knob                    matlab.ui.control.Knob
        Q1KnobLabel               matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Global Variables
        device 
        theta1_dyn = 511; 
        theta2_dyn = 511;
        theta3_dyn = 511;
        COM = "COM4"
        theta1_deg = 150;
        theta2_deg = 150;
        theta3_deg = 150;
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % Start the serial communication 
            disp("Starting Serial Communication") 
            app.device = serialport(app.COM, 9600); 

            % Reads until it gets the new line character 
            configureTerminator(app.device, "LF") 
            disp("Reading Starting Message from Arduino") 
            while app.device.NumBytesAvailable ~= 0 
                disp(readline(app.device)) 
            end 
            pause(1.0) 

            app.q1_valueLabel.Text = "0°";
            app.q2_valueLabel.Text = "0°";
            app.q3_valueLabel.Text = "0°";
        end

        % Value changing function: Q1Knob
        function Q1KnobValueChanging(app, event)
            app.theta1_deg = event.Value + 150;
            app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
            app.theta1_dyn = app.theta1_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
               
            % Send q values to Arduino
            send_q_values(app.device, theta_array);
        end

        % Value changing function: Q2Knob
        function Q2KnobValueChanging(app, event)
            app.theta2_deg = event.Value + 240;
            app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
            app.theta2_dyn = app.theta2_deg * 1023 / 300;
            
            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 

            % Send q values to Arduino
            send_q_values(app.device, theta_array);
        end

        % Value changing function: Q3Knob
        function Q3KnobValueChanging(app, event)
            app.theta3_deg = event.Value + 150;
            app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
            app.theta3_dyn = app.theta3_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">");
            
            % Send q values to Arduino
            send_q_values(app.device, theta_array);
        end

        % Value changed function: Q1Knob
        function Q1KnobValueChanged(app, event)
            app.theta1_deg = app.Q1Knob.Value + 150;
            app.q1_valueLabel.Text = strcat(int2str(app.theta1_deg - 150), "°");
            app.theta1_dyn = app.theta1_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 
            
            % Send q values to Arduino
            send_q_values(app.device, theta_array);
        end

        % Value changed function: Q2Knob
        function Q2KnobValueChanged(app, event)
            app.theta2_deg = app.Q2Knob.Value + 240;
            app.q2_valueLabel.Text = strcat(int2str(app.theta2_deg - 240), "°");
            app.theta2_dyn = app.theta2_deg * 1023 / 300;
            
            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 

            % Send q values to Arduino
            send_q_values(app.device, theta_array);
        end

        % Value changed function: Q3Knob
        function Q3KnobValueChanged(app, event)
            app.theta3_deg = app.Q3Knob.Value + 150;
            app.q3_valueLabel.Text = strcat(int2str(app.theta3_deg - 150), "°");
            app.theta3_dyn = app.theta3_deg * 1023 / 300;

            % Write the string of data to the Arduino 
            theta_array = strcat("<", int2str(app.theta1_dyn), ",", int2str(app.theta2_dyn), ",", int2str(app.theta3_dyn), ">"); 

            % Send q values to Arduino
            send_q_values(app.device, theta_array);
        end

        % Value changed function: SerialPortEditField
        function SerialPortEditFieldValueChanged(app, event)
            app.COM = app.SerialPortEditField.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Get DH parameters
            [DH, q] = DH_parameters(app.theta1_deg - 150, app.theta2_deg - 240, app.theta3_deg - 150);
            
            % Direct Geometric Model
            [Tn, T0Tn, X, Y, Z] = DGM(DH, q);
    
            plot3(app.UIAxes, X, Y, Z, '-ro', 'linewidth', 3);

            % Shows end effector position
            app.X_valueLabel.Text = strcat(num2str(round(T0Tn{end}(1,4), 2)), " mm");
            app.Y_valueLabel.Text = strcat(num2str(round(T0Tn{end}(2,4), 2)), " mm");
            app.Z_valueLabel.Text = strcat(num2str(round(T0Tn{end}(3,4), 2)), " mm");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Configuration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-300 300];
            app.UIAxes.YLim = [-300 300];
            app.UIAxes.ZLim = [0 330];
            app.Q1KnobLabel = uilabel(app.UIFigure);
            app.Q1KnobLabel.Text = 'Q1';
            app.Q1Knob = uiknob(app.UIFigure, 'continuous');
            app.Q1Knob.Limits = [-150 150];
            app.Q1Knob.ValueChangedFcn = createCallbackFcn(app, @Q1KnobValueChanged, true);
            app.Q1Knob.ValueChangingFcn = createCallbackFcn(app, @Q1KnobValueChanging, true);
            app.Q2KnobLabel = uilabel(app.UIFigure);
            app.Q2KnobLabel.Text = 'Q2';
            app.Q2Knob = uiknob(app.UIFigure, 'continuous');
            app.Q2Knob.Limits = [-240 60];
            app.Q2Knob.ValueChangedFcn = createCallbackFcn(app, @Q2KnobValueChanged, true);
            app.Q2Knob.ValueChangingFcn = createCallbackFcn(app, @Q2KnobValueChanging, true);
            app.Q3KnobLabel = uilabel(app.UIFigure);
            app.Q3KnobLabel.Text = 'Q3';
            app.Q3Knob = uiknob(app.UIFigure, 'continuous');
            app.Q3Knob.Limits = [-150 150];
            app.Q3Knob.ValueChangedFcn = createCallbackFcn(app, @Q3KnobValueChanged, true);
            app.Q3Knob.ValueChangingFcn = createCallbackFcn(app, @Q3KnobValueChanging, true);
            app.ManualModeLabel = uilabel(app.UIFigure);
            app.ManualModeLabel.Text = 'Manual Mode';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.q1_valueLabel = uilabel(app.UIFigure);
            app.q1_valueLabel.Text = 'q1_value';
            app.q2_valueLabel = uilabel(app.UIFigure);
            app.q2_valueLabel.Text = 'q2_value';
            app.q3_valueLabel = uilabel(app.UIFigure);
            app.q3_valueLabel.Text = 'q3_value';
            app.SerialPortEditFieldLabel = uilabel(app.UIFigure);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.UIFigure, 'text');
            app.SerialPortEditField.ValueChangedFcn = createCallbackFcn(app, @SerialPortEditFieldValueChanged, true);
            app.ArduinoConnectionLabel = uilabel(app.UIFigure);
            app.ArduinoConnectionLabel.Text = 'Arduino Connection';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ForwardKinematicsTab = uitab(app.TabGroup);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.ForwardKinematicsLabel = uilabel(app.ForwardKinematicsTab);
            app.ForwardKinematicsLabel.Text = {'Forward Kinematics'; ''};
            app.CalculateButton = uibutton(app.ForwardKinematicsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PositionXLabel = uilabel(app.ForwardKinematicsTab);
            app.PositionXLabel.Text = 'Position X:';
            app.PositionYLabel = uilabel(app.ForwardKinematicsTab);
            app.PositionYLabel.Text = 'Position Y:';
            app.PositionZLabel = uilabel(app.ForwardKinematicsTab);
            app.PositionZLabel.Text = 'Position Z:';
            app.X_valueLabel = uilabel(app.ForwardKinematicsTab);
            app.X_valueLabel.Text = 'X_value';
            app.Y_valueLabel = uilabel(app.ForwardKinematicsTab);
            app.Y_valueLabel.Text = 'Y_value';
            app.Z_valueLabel = uilabel(app.ForwardKinematicsTab);
            app.Z_valueLabel.Text = 'Z_value';
            app.InverseKinematicsTab = uitab(app.TabGroup);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.XEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.EntervaluesButton = uibutton(app.InverseKinematicsTab, 'push');
            app.EntervaluesButton.Text = 'Enter values';
            app.InverseKinematicsLabel = uilabel(app.InverseKinematicsTab);
            app.InverseKinematicsLabel.Text = {'Inverse Kinematics'; ''};
            app.DesiredPositionLabel = uilabel(app.InverseKinematicsTab);
            app.DesiredPositionLabel.Text = 'Desired Position';
            app.mmLabel = uilabel(app.InverseKinematicsTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.InverseKinematicsTab);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.InverseKinematicsTab);
            app.mmLabel_3.Text = 'mm';
            app.SolutionsLabel = uilabel(app.InverseKinematicsTab);
            app.SolutionsLabel.Text = 'Solutions';
            app.TrajectoryPlanningTab = uitab(app.TabGroup);
            app.TrajectoryPlanningTab.Title = 'Trajectory Planning';
            app.TrajectoryPlanningLabel = uilabel(app.TrajectoryPlanningTab);
            app.TrajectoryPlanningLabel.Text = 'Trajectory Planning';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end